classdef microgliaTimelapseRemovalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        saveButton      matlab.ui.control.Button
        LoadFileButton  matlab.ui.control.Button
        Slider          matlab.ui.control.Slider
        SliderLabel     matlab.ui.control.Label
        imageWaveColAx  matlab.ui.control.UIAxes
        imageDFAx       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        disStack % Description
        exStruct
        col
        cellNum
        cellDelFlag 
        scatterH
        scatterH2
    end
    
    methods (Access = private)
        
        function app = loadImages(app)

            exStruct = app.exStruct;

            % get filepath strings
            timeLapse_filepath = [exStruct.filePath(1:end-4) '.tif'];
            labelFilepath = [exStruct.filePath(1:end-4) '-labelMasks.tif'];

            % intial color mask set up
            app.cellNum = max(app.exStruct.csvTab.Object_Label);
            app.col = distinguishable_colors(app.cellNum,'k');
            app.col = [0 0 0; app.col];

            % load in dF images and waveCol Images
            timeLapseTifStack = read_Tiffs(timeLapse_filepath);

            disStack.dFTifStack = imadjustn(timeLapseTifStack);
            labelStack = tiffreadVolume(labelFilepath);

            for i = 1:size(labelStack,3)
                disStack.labelStack(:,:,i,:) = ind2rgb(labelStack(:,:,i),app.col);
            end

            app.disStack = disStack;

            % set up cell delete flags
            app.cellDelFlag = false(app.cellNum,1);

            % resize image axis to image size
            app.imageDFAx.DataAspectRatio = [size(timeLapseTifStack,1), size(timeLapseTifStack,2),1];
            app.imageDFAx.XLim = [ 0 size(timeLapseTifStack,1)];
            app.imageDFAx.YLim = [ 0 size(timeLapseTifStack,1)];

            app.imageWaveColAx.DataAspectRatio = [size(timeLapseTifStack,1), size(timeLapseTifStack,2),1];
            app.imageWaveColAx.XLim = [ 0 size(timeLapseTifStack,1)];
            app.imageWaveColAx.YLim = [ 0 size(timeLapseTifStack,1)];

            linkaxes([app.imageDFAx,app.imageWaveColAx]);
            

            % set images and slider
            frame = 1;
            setImagesSlider(app, frame);

             % bring to front
                figure(app.UIFigure);
        end
        
        function results = setImagesSlider(app, frame)

            imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));
            app.imageDFAx.Children.ButtonDownFcn = app.imageDFAx.ButtonDownFcn; % copy button down function to image

            imagesc(app.imageWaveColAx,squeeze(app.disStack.labelStack(:,:,frame,:)));
            app.imageWaveColAx.Children.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn; % copy button down function to image

            app.Slider.Value = frame;

            plotDelCells(app);

        end
        
        function cellIndx = findClosestCell(app, clickPt)

            currentFrameObj = app.exStruct.csvTab(app.exStruct.csvTab.Centroid_Time_Frames == app.Slider.Value,:);

            currentXY = [currentFrameObj.Centroid_X_Pixel, currentFrameObj.Centroid_Y_Pixel];
            [d,indx] = pdist2(currentXY,clickPt,"euclidean","Smallest",1);

            cellIndx =currentFrameObj.Object_Label(indx);
            
        end
        
        function results = plotDelCells(app)
            delete(app.scatterH);
            delete(app.scatterH2);

            pause(0.1);

            currentFrameObj = app.exStruct.csvTab(app.exStruct.csvTab.Centroid_Time_Frames == app.Slider.Value,:);

            cell2Plot = find(app.cellDelFlag);

            if ~isempty(cell2Plot)

                count = 1;
                for tt = 1:length(cell2Plot)

                    if ismember(cell2Plot(tt),currentFrameObj.Object_Label)
                        plotX(count) = currentFrameObj.Centroid_X_Pixel(currentFrameObj.Object_Label == cell2Plot(tt));
                        plotY(count) = currentFrameObj.Centroid_Y_Pixel(currentFrameObj.Object_Label == cell2Plot(tt));
                        count = count+1;
                    end
                end

                hold(app.imageWaveColAx,'on');
                app.scatterH = scatter(app.imageWaveColAx,plotX, plotY,160,'cy*');
                app.scatterH.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn;
                hold(app.imageWaveColAx,'off');


                hold(app.imageDFAx,'on');
                app.scatterH2 = scatter(app.imageDFAx,plotX, plotY,160,'cy*');
                app.scatterH2.ButtonDownFcn = app.imageDFAx.ButtonDownFcn;
                hold(app.imageDFAx,'off');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % center figure on startup
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.csv'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                csvTab = readtable([p f]);
                app.exStruct.csvTab = csvTab;
                app.exStruct.filePath = [p f];
                loadImages(app);

                % intial slider setup
                app.Slider.Limits = [1 size(app.disStack.labelStack,3)];
                app.Slider.MajorTicks = [1 : size(app.disStack.labelStack,3)];
                app.Slider.Value = 1;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value); % integer round

            app.Slider.Value = frame; % set
            
            setImagesSlider(app, frame);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'rightarrow' % increase frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame +1; % set

                    if newVal >app.Slider.Limits(2)
                        newVal = app.Slider.Limits(2);
                    end

                    setImagesSlider(app, newVal);

                case 'leftarrow' % decrease frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame -1; % set

                    if newVal < app.Slider.Limits(1)
                        newVal = app.Slider.Limits(1);
                    end

                    setImagesSlider(app, newVal)

            end
            
        end

        % Window scroll wheel function: UIFigure
        function ScrollWheelCallbck(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            frame = round(app.Slider.Value); % integer round
            newVal = frame  + verticalScrollCount; % set

            if newVal < app.Slider.Limits(1)
                newVal = app.Slider.Limits(1);
            elseif newVal >app.Slider.Limits(2)
                newVal = app.Slider.Limits(2);
            end

            setImagesSlider(app, newVal)
            
        end

        % Button down function: imageWaveColAx
        function imageWaveColAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % find closest cell center
            cellIndx = findClosestCell(app, clickPt);

            % flip the logical
            app.cellDelFlag(cellIndx) = ~app.cellDelFlag(cellIndx);

            plotDelCells(app)

        end

        % Button down function: imageDFAx
        function imageDFAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % find closest cell center
            cellIndx = findClosestCell(app, clickPt);

            % flip the logical
            app.cellDelFlag(cellIndx) = ~app.cellDelFlag(cellIndx);

            plotDelCells(app)

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            cell2DeleteFlagFile = find(app.cellDelFlag);

            cellFlags = zeros(height(app.exStruct.csvTab),1);
            for c = 1: length(cell2DeleteFlagFile)
  
                curCell = (cell2DeleteFlagFile(c));
                cellFlags(app.exStruct.csvTab.Object_Label==curCell) = 1;
            end

            app.exStruct.csvTab.object2Remove = cellFlags;

            writetable(app.exStruct.csvTab,[app.exStruct.filePath(1:end-4) '_corrected.csv']);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollWheelCallbck, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageDFAx = uiaxes(app.UIFigure);
            app.imageDFAx.XLim = [0 512];
            app.imageDFAx.YLim = [0 512];
            app.imageDFAx.XTick = [];
            app.imageDFAx.YTick = [];
            app.imageDFAx.ZTick = [];
            app.imageDFAx.ButtonDownFcn = createCallbackFcn(app, @imageDFAxButtonDown, true);
            colormap(app.imageDFAx, 'gray')
            app.imageWaveColAx = uiaxes(app.UIFigure);
            app.imageWaveColAx.XLim = [0 512];
            app.imageWaveColAx.YLim = [0 512];
            app.imageWaveColAx.XTick = [];
            app.imageWaveColAx.YTick = [];
            app.imageWaveColAx.ZTick = [];
            app.imageWaveColAx.ButtonDownFcn = createCallbackFcn(app, @imageWaveColAxButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load in Experiment CSV';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save labelled Experiment CSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microgliaTimelapseRemovalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end